classdef sight_line < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        horizontSlider       matlab.ui.control.Slider
        horizontSliderLabel  matlab.ui.control.Label
        verticalSlider       matlab.ui.control.Slider
        verticalSliderLabel  matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        DOWNButton           matlab.ui.control.Button
        UPButton             matlab.ui.control.Button
        BACKButton           matlab.ui.control.Button
        LEFTButton           matlab.ui.control.Button
        RIGHTButton          matlab.ui.control.Button
        z                    matlab.ui.control.TextArea
        y                    matlab.ui.control.TextArea
        x                    matlab.ui.control.TextArea
        FORButton            matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        function pos_update(app, x_dir, y_dir, z_dir)
            % 更新位置数据
            CHANGE_SIZE = 1;
            app.x.Value = num2str(str2double(app.x.Value) + x_dir * CHANGE_SIZE);
            app.y.Value = num2str(str2double(app.y.Value) + y_dir * CHANGE_SIZE);
            app.z.Value = num2str(str2double(app.z.Value) + z_dir * CHANGE_SIZE);
        end

        function line_calculate(app)
            % 常量
            TEXTURE_RES = 1;
            MAP_SIZE = [5, 5, 5];
            LOOKAT_REL_FAC = 0.02;
            ANGLE_RADIUS = 225;

            % 获取位置、角度数据
            p_pos = [str2double(app.x.Value), str2double(app.y.Value), str2double(app.z.Value)];
            p_angle = [app.horizontSlider.Value, app.verticalSlider.Value];
            
            % 清空
            cla(app.UIAxes);
            % 画 x 轴
            plot3(app.UIAxes, [-TEXTURE_RES*MAP_SIZE(1) TEXTURE_RES*MAP_SIZE(1)], [0 0], [0 0], 'r', 'LineWidth', 2);
            text(app.UIAxes, TEXTURE_RES*MAP_SIZE(1), 0, 0, 'X', 'FontSize', 12);
            hold(app.UIAxes, 'on');  % 开启保留图形
            rotate3d(app.UIAxes, 'on');  % 鼠标旋转
            grid(app.UIAxes, 'on');
            % 画 y 轴
            plot3(app.UIAxes, [0 0], [-TEXTURE_RES*MAP_SIZE(2) TEXTURE_RES*MAP_SIZE(2)], [0 0], 'g', 'LineWidth', 2);
            text(app.UIAxes, 0, TEXTURE_RES*MAP_SIZE(2), 0, 'Y', 'FontSize', 12);
            % 画 z 轴
            plot3(app.UIAxes, [0 0], [0 0], [-TEXTURE_RES*MAP_SIZE(3) TEXTURE_RES*MAP_SIZE(3)], 'b', 'LineWidth', 2);
            text(app.UIAxes, 0, 0, TEXTURE_RES*MAP_SIZE(3), 'Z', 'FontSize', 12);


            % 射线计算
            coord_h = [round(sqrt(ANGLE_RADIUS^2 - p_angle(1)^2)), p_angle(1)];
            coord_v = [round(sqrt(ANGLE_RADIUS^2 - p_angle(2)^2)), p_angle(2)];
            lookat_rel(1) = coord_h(1) * coord_v(1) / ANGLE_RADIUS;
            lookat_rel(2) = coord_h(2) * coord_v(1) / ANGLE_RADIUS;
            lookat_rel(3) = coord_v(2);
            lookat_h_rel(1) = coord_h(1);
            lookat_h_rel(2) = coord_h(2);
            vp_u = [lookat_h_rel(2), -lookat_h_rel(1), 0];
            vp_v(1) = ((vp_u(2) * lookat_rel(3)) - (vp_u(3) * lookat_rel(2))) / ANGLE_RADIUS;
            vp_v(2) = ((vp_u(3) * lookat_rel(1)) - (vp_u(1) * lookat_rel(3))) / ANGLE_RADIUS;
            vp_v(3) = ((vp_u(1) * lookat_rel(2)) - (vp_u(2) * lookat_rel(1))) / ANGLE_RADIUS;
            vp_origin(1) = p_pos(1) + lookat_rel(1) * LOOKAT_REL_FAC;
            vp_origin(2) = p_pos(2) + lookat_rel(2) * LOOKAT_REL_FAC;
            vp_origin(3) = p_pos(3) + lookat_rel(3) * LOOKAT_REL_FAC;
            % 计算起始点、结束点
            start_p = p_pos;
            end_p = vp_origin;
            % 画出线段
            plot3(app.UIAxes, [start_p(1), end_p(1)], [start_p(2), end_p(2)], [start_p(3), end_p(3)], 'k-', 'LineWidth', 2);

            % 
            block_p = fix(p_pos / TEXTURE_RES);
            hit_P = p_pos;
            scatter3(app.UIAxes, block_p(1)*TEXTURE_RES, block_p(2)*TEXTURE_RES, block_p(3)*TEXTURE_RES, 10, 'filled', 'MarkerFaceColor', 'b');
            next_block_p = [0, 0, 0];
            for i=1:1:3
                if end_p(i) <= start_p(i)
                    next_block_p(i) = block_p(i);
                else
                    next_block_p(i) = block_p(i) + 1;
                end
            end
            edge = [next_block_p(1)*TEXTURE_RES, next_block_p(2)*TEXTURE_RES, next_block_p(3)*TEXTURE_RES];
            scatter3(app.UIAxes, edge(1), edge(2), edge(3), 10, 'filled', 'MarkerFaceColor', 'b');
            
            % 
            plot3(app.UIAxes, [start_p(1), edge(1)], [start_p(2), start_p(2)], [start_p(3), start_p(3)], 'r', 'LineWidth', 2);
            plot3(app.UIAxes, [start_p(1), start_p(1)], [start_p(2), edge(2)], [start_p(3), start_p(3)], 'g', 'LineWidth', 2);
            plot3(app.UIAxes, [start_p(1), start_p(1)], [start_p(2), start_p(2)], [start_p(3), edge(3)], 'b', 'LineWidth', 2);
            
            delta = edge - start_p;
            delta_t = [0, 0, 0];
            for i=1:1:3
                delta_t(i) = delta(i) / lookat_rel(i);
            end
            next_x = [edge(1), p_pos(2) + lookat_rel(2)*delta_t(1),p_pos(3) + lookat_rel(3)*delta_t(1)];
            next_y = [p_pos(1) + lookat_rel(1)*delta_t(2), edge(2),p_pos(3) + lookat_rel(3)*delta_t(2)];
            next_z = [p_pos(1) + lookat_rel(1)*delta_t(3), p_pos(2) + lookat_rel(2)*delta_t(3),edge(3)];
            scatter3(app.UIAxes, next_x(1), next_x(2), next_x(3), 10, 'filled', 'MarkerFaceColor', 'b');
            scatter3(app.UIAxes, next_y(1), next_y(2), next_y(3), 10, 'filled', 'MarkerFaceColor', 'b');
            scatter3(app.UIAxes, next_z(1), next_z(2), next_z(3), 10, 'filled', 'MarkerFaceColor', 'b');
            plot3(app.UIAxes, [edge(1), next_x(1)], [start_p(2), next_x(2)], [start_p(3), next_x(3)], 'r', 'LineWidth', 2);
            plot3(app.UIAxes, [start_p(1), next_y(1)], [edge(2), next_y(2)], [start_p(3), next_y(3)], 'g', 'LineWidth', 2);
            plot3(app.UIAxes, [start_p(1), next_z(1)], [start_p(2), next_z(2)], [edge(3), next_z(3)], 'b', 'LineWidth', 2);


            view(app.UIAxes, 45, 30);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function axes_func(app, event)

        end

        % Value changing function: horizontSlider
        function handle_Hchanging(app, event)
            app.line_calculate();
        end

        % Value changing function: verticalSlider
        function handle_Vchanging(app, event)
            app.line_calculate();
        end

        % Button pushed function: FORButton
        function handle_FORchanging(app, event)
            app.pos_update(1, 0, 0);
            app.line_calculate();
        end

        % Button pushed function: BACKButton
        function handle_BACKchanging(app, event)
            app.pos_update(-1, 0, 0);
            app.line_calculate();
        end

        % Button pushed function: RIGHTButton
        function handle_RIGHTchanging(app, event)
            app.pos_update(0, 1, 0);
            app.line_calculate();
        end

        % Button pushed function: LEFTButton
        function handle_LEFTchanging(app, event)
            app.pos_update(0, -1, 0);
            app.line_calculate();
        end

        % Button pushed function: UPButton
        function handle_UPchanging(app, event)
            app.pos_update(0, 0, 1);
            app.line_calculate();
        end

        % Button pushed function: DOWNButton
        function handle_DOWNchanging(app, event)
            app.pos_update(0, 0, -1);
            app.line_calculate();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {142, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.verticalSliderLabel = uilabel(app.LeftPanel);
            app.verticalSliderLabel.Text = 'vertical';
            app.verticalSlider = uislider(app.LeftPanel);
            app.verticalSlider.Limits = [0 158];
            app.verticalSlider.ValueChangingFcn = createCallbackFcn(app, @handle_Vchanging, true);
            app.verticalSlider.Value = 120;
            app.horizontSliderLabel = uilabel(app.LeftPanel);
            app.horizontSliderLabel.Text = 'horizont';
            app.horizontSlider = uislider(app.LeftPanel);
            app.horizontSlider.Limits = [0 158];
            app.horizontSlider.ValueChangingFcn = createCallbackFcn(app, @handle_Hchanging, true);
            app.horizontSlider.Value = 60;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Sight Line')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @axes_func, true);
            app.FORButton = uibutton(app.RightPanel, 'push');
            app.FORButton.ButtonPushedFcn = createCallbackFcn(app, @handle_FORchanging, true);
            app.FORButton.Text = 'FOR';
            app.x = uitextarea(app.RightPanel);
            app.x.Value = {'1.1'};
            app.y = uitextarea(app.RightPanel);
            app.y.Value = {'1.2'};
            app.z = uitextarea(app.RightPanel);
            app.z.Value = {'1.3'};
            app.RIGHTButton = uibutton(app.RightPanel, 'push');
            app.RIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @handle_RIGHTchanging, true);
            app.RIGHTButton.Text = 'RIGHT';
            app.LEFTButton = uibutton(app.RightPanel, 'push');
            app.LEFTButton.ButtonPushedFcn = createCallbackFcn(app, @handle_LEFTchanging, true);
            app.LEFTButton.Text = 'LEFT';
            app.BACKButton = uibutton(app.RightPanel, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @handle_BACKchanging, true);
            app.BACKButton.Text = 'BACK';
            app.UPButton = uibutton(app.RightPanel, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @handle_UPchanging, true);
            app.UPButton.Text = 'UP';
            app.DOWNButton = uibutton(app.RightPanel, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @handle_DOWNchanging, true);
            app.DOWNButton.Text = 'DOWN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sight_line

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end